classdef allModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputsPanel                     matlab.ui.container.Panel
        SeverityscoreDropDownLabel      matlab.ui.control.Label
        SeverityScore                   matlab.ui.control.DropDown
        VarName1                        matlab.ui.control.EditField
        VarName2                        matlab.ui.control.EditField
        VarName3                        matlab.ui.control.EditField
        VarName4                        matlab.ui.control.EditField
        VarName5                        matlab.ui.control.EditField
        UnitsLabel                      matlab.ui.control.Label
        TreatmenttypeLabel              matlab.ui.control.Label
        TreatmentType                   matlab.ui.control.DropDown
        Input1                          matlab.ui.control.Slider
        Input2                          matlab.ui.control.Slider
        Input3                          matlab.ui.control.Slider
        Input4                          matlab.ui.control.Slider
        Input5                          matlab.ui.control.Slider
        InitialScoreLabel               matlab.ui.control.Label
        InitialScore                    matlab.ui.control.Slider
        PredictionPanel                 matlab.ui.container.Panel
        PredictionGraph                 matlab.ui.control.UIAxes
        ImprovementEditFieldLabel       matlab.ui.control.Label
        Improvement                     matlab.ui.control.EditField
        ModelPerformancePanel           matlab.ui.container.Panel
        AccuracyEditFieldLabel          matlab.ui.control.Label
        Accuracy                        matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        RMSE                            matlab.ui.control.NumericEditField
        SystemicTherapyPredictionLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function recalculate(app)
            
            % Extract the inputs
            biomarkers = [app.Input1.Value; app.Input2.Value; app.Input3.Value; app.Input4.Value; app.Input5.Value;];
            isAza = isequal(app.TreatmentType.Value, 'AZA');
            initScore = app.InitialScore.Value;
            
            % Calculate the predictions
            scoreType = app.SeverityScore.Value;
            if isequal(scoreType, 'oSCORAD')
                [weekPredictions, imprStatus] = oSCORADPred(biomarkers, isAza, initScore);
                maxScore = 83;
            elseif isequal(scoreType, 'SCORAD')
                [weekPredictions, imprStatus] = totSCORADPred(biomarkers, isAza, initScore);
                maxScore = 103;
            elseif isequal(scoreType, 'EASI')
                [weekPredictions, imprStatus] = easiPred(biomarkers, isAza, initScore);
                maxScore = 72;
            else
                error('Error determining score type.');
            end
            
            % Display the improvement status
            try
                maxWeeks = 24;
                plot(app.PredictionGraph, [0:maxWeeks], weekPredictions, 'x');
                xlim(app.PredictionGraph,[0 maxWeeks]);
                ylim(app.PredictionGraph,[0 maxScore]);
                app.Improvement.Value = imprStatus;
            catch
                error('Invalid inputs.');
            end
            
        end
        
        function oSCORADSetUp(app)
            %Set variable names
            varNames = ['VEGF '; 'IL7  '; 'TNFa '; 'MIP1b'; 'IL10 '];
            setVarNames(app, varNames);
            
            % Set score limits
            setUpInitialScore(app, 83);
            
            % Set input limits
            means = [39.79; 9.63; 8.37; 135.28; 12.26]; 
            stdDevs = [37.87; 7.72; 7.26; 82.84; 58.87];
            setLimits(app, means, stdDevs);
            setMeans(app, means);
            
            % Set model statistics
            app.Accuracy.Value = 0.724;
            app.RMSE.Value = 8.15;
            
            % Set graph axes
            ylim(app.PredictionGraph,[0 83]);
            
        end
        
        function totSCORADSetUp(app)
            % Set variable names
            varNames = ['VEGF '; 'IL18 '; 'IL7  '; 'CTACK'; 'IL10 '];
            setVarNames(app, varNames);
            
            % Set score limits
            setUpInitialScore(app, 103);
            
            % Set input limits
            means = [39.79; 15.68; 9.63; 2430; 12.26];
            stdDevs = [37.87; 33.41; 7.72; 1348; 58.87]; 
            setLimits(app, means, stdDevs);
            setMeans(app, means);
            
            % Set model statistics
            app.Accuracy.Value = 0.645;
            app.RMSE.Value = 10.50;
            
            % Set graph axes
            ylim(app.PredictionGraph,[0 103]);
        end
        
        function easiSetUp(app)
            % Set variable names
            varNames = ['IL7  '; 'TNFa '; 'IL31 '; 'MIP1b'; 'IL10 '];
            setVarNames(app, varNames);
            
            % Set score limits
            setUpInitialScore(app, 72);
            
            % Set input limits
            means = [9.63; 8.37; 485.24; 135.28; 12.26];
            stdDevs = [7.72; 7.26; 1117.9; 82.84; 58.87];
            setLimits(app, means, stdDevs);
            setMeans(app, means);
            
            % Set model statistics
            app.Accuracy.Value = 0.803;
            app.RMSE.Value = 6.33;
            
            % Set graph axes
            ylim(app.PredictionGraph,[0 72]);
        end
        
        function setMeans(app, means)
            % Round means
            means = round(means, 2, 'significant');
            
            % Set mean values
            app.Input1.Value = means(1);
            app.Input2.Value = means(2);
            app.Input3.Value = means(3);
            app.Input4.Value = means(4);
            app.Input5.Value = means(5);
        end
        
        function setLimits(app, means, stdDevs)
            % Calculate limits
            numStdDevs = 2;
            upperLimit = means + (numStdDevs * stdDevs);
            lowerLimit = means - (numStdDevs * stdDevs);
            lowerLimit = thresholdData(lowerLimit, 0, 999); 
            
            % Round limits
            upperLimit = directionalSFRound(upperLimit, 2, true);
            lowerLimit = directionalSFRound(lowerLimit, 2, false);
            
            % Calculate divisions
            numMajorTicks = 6;
            divisions = (upperLimit - lowerLimit) / (numMajorTicks - 1);
            divisions = round(divisions, 1, 'significant');
            upperLimit = lowerLimit + (divisions * (numMajorTicks - 1));
            
            % Set limits
            app.Input1.Limits = [lowerLimit(1) upperLimit(1)];
            app.Input2.Limits = [lowerLimit(2) upperLimit(2)];
            app.Input3.Limits = [lowerLimit(3) upperLimit(3)];
            app.Input4.Limits = [lowerLimit(4) upperLimit(4)];
            app.Input5.Limits = [lowerLimit(5) upperLimit(5)];
            
            % Set major ticks
            app.Input1.MajorTicks = round(linspace(lowerLimit(1), upperLimit(1), numMajorTicks));
            app.Input2.MajorTicks = round(linspace(lowerLimit(2), upperLimit(2), numMajorTicks));
            app.Input3.MajorTicks = round(linspace(lowerLimit(3), upperLimit(3), numMajorTicks));
            app.Input4.MajorTicks = round(linspace(lowerLimit(4), upperLimit(4), numMajorTicks));
            app.Input5.MajorTicks = round(linspace(lowerLimit(5), upperLimit(5), numMajorTicks));
 
            % Set minor ticks
            numSubDivisions = 3;
            numMinorTicks =  numSubDivisions * (numMajorTicks - 1) + 1;
            app.Input1.MinorTicks = round(linspace(lowerLimit(1), upperLimit(1), numMinorTicks));
            app.Input2.MinorTicks = round(linspace(lowerLimit(2), upperLimit(2), numMinorTicks));
            app.Input3.MinorTicks = round(linspace(lowerLimit(3), upperLimit(3), numMinorTicks));
            app.Input4.MinorTicks = round(linspace(lowerLimit(4), upperLimit(4), numMinorTicks));
            app.Input5.MinorTicks = round(linspace(lowerLimit(5), upperLimit(5), numMinorTicks));
           
        end
        
        function setVarNames(app, varNames)
            app.VarName1.Value = varNames(1, :);
            app.VarName2.Value = varNames(2, :);
            app.VarName3.Value = varNames(3, :);
            app.VarName4.Value = varNames(4, :);
            app.VarName5.Value = varNames(5, :);
        end
        
        function setUpInitialScore(app, maxScore)        
            % Set score limits
            app.InitialScore.Limits = [0 maxScore];
            app.InitialScore.Value = maxScore / 2;
            
            % Set major ticks
            numMajorTicks = 6;
            app.InitialScore.MajorTicks = round(linspace(0, maxScore, numMajorTicks));
            
            % Set minor ticks
            numSubDivisions = 3;
            numMinorTicks =  numSubDivisions * (numMajorTicks - 1) + 1;
            app.InitialScore.MinorTicks = round(linspace(0, maxScore, numMinorTicks));
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up for oSCORAD (default)
            oSCORADSetUp(app);
            
            % Set y axis limits
            xlim(app.PredictionGraph,[0 24]);
            
            % First calculation
            recalculate(app);
        end

        % Value changed function: InitialScore, Input1, Input2, 
        % Input3, Input4, Input5
        function InitialScoreValueChanged(app, event)
            recalculate(app);
        end

        % Value changed function: SeverityScore
        function SeverityScoreValueChanged(app, event)
            value = app.SeverityScore.Value;
            
            % Set graph y axis
            app.PredictionGraph.YLabel.String = value;
            
            % Set input labels, graph axes, and accuracy
            if isequal(value, 'oSCORAD')
                oSCORADSetUp(app);
            elseif isequal(value, 'SCORAD')
                totSCORADSetUp(app);
            elseif isequal(value, 'EASI')
                easiSetUp(app);
            else
                error('Error determining severity score type.');
            end
            
            % Recalculate based on new data
            recalculate(app);
        end

        % Value changed function: TreatmentType
        function TreatmentTypeValueChanged(app, event)
            recalculate(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SeverityscoreDropDownLabel = uilabel(app.InputsPanel);
            app.SeverityscoreDropDownLabel.Text = 'Severity score';
            app.SeverityScore = uidropdown(app.InputsPanel);
            app.SeverityScore.Items = {'oSCORAD', 'SCORAD', 'EASI'};
            app.SeverityScore.ValueChangedFcn = createCallbackFcn(app, @SeverityScoreValueChanged, true);
            app.SeverityScore.Value = 'oSCORAD';
            app.VarName1 = uieditfield(app.InputsPanel, 'text');
            app.VarName1.Editable = 'off';
            app.VarName1.Value = 'VEGF';
            app.VarName2 = uieditfield(app.InputsPanel, 'text');
            app.VarName2.Editable = 'off';
            app.VarName2.Value = 'IL7';
            app.VarName3 = uieditfield(app.InputsPanel, 'text');
            app.VarName3.Editable = 'off';
            app.VarName3.Value = 'TNFa';
            app.VarName4 = uieditfield(app.InputsPanel, 'text');
            app.VarName4.Editable = 'off';
            app.VarName4.Value = 'MIP1b';
            app.VarName5 = uieditfield(app.InputsPanel, 'text');
            app.VarName5.Editable = 'off';
            app.VarName5.Value = 'IL10';
            app.UnitsLabel = uilabel(app.InputsPanel);
            app.UnitsLabel.Text = 'All values should be entered in pg/ml.';
            app.TreatmenttypeLabel = uilabel(app.InputsPanel);
            app.TreatmenttypeLabel.Text = 'Treatment type';
            app.TreatmentType = uidropdown(app.InputsPanel);
            app.TreatmentType.Items = {'AZA', 'MTX'};
            app.TreatmentType.ValueChangedFcn = createCallbackFcn(app, @TreatmentTypeValueChanged, true);
            app.TreatmentType.Value = 'AZA';
            app.Input1 = uislider(app.InputsPanel);
            app.Input1.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.Input1.MinorTicks = [];
            app.Input2 = uislider(app.InputsPanel);
            app.Input2.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.Input2.MinorTicks = [];
            app.Input3 = uislider(app.InputsPanel);
            app.Input3.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.Input3.MinorTicks = [];
            app.Input4 = uislider(app.InputsPanel);
            app.Input4.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.Input4.MinorTicks = [];
            app.Input5 = uislider(app.InputsPanel);
            app.Input5.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.Input5.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.Input5.MinorTicks = [];
            app.InitialScoreLabel = uilabel(app.InputsPanel);
            app.InitialScoreLabel.Text = 'Initial Score';
            app.InitialScore = uislider(app.InputsPanel);
            app.InitialScore.ValueChangedFcn = createCallbackFcn(app, @InitialScoreValueChanged, true);
            app.PredictionPanel = uipanel(app.UIFigure);
            app.PredictionPanel.Title = 'Prediction';
            app.PredictionGraph = uiaxes(app.PredictionPanel);
            xlabel(app.PredictionGraph, 'Week number')
            ylabel(app.PredictionGraph, 'oSCORAD')
            app.ImprovementEditFieldLabel = uilabel(app.PredictionPanel);
            app.ImprovementEditFieldLabel.Text = 'Improvement';
            app.Improvement = uieditfield(app.PredictionPanel, 'text');
            app.Improvement.Editable = 'off';
            app.ModelPerformancePanel = uipanel(app.UIFigure);
            app.ModelPerformancePanel.Title = 'Model Performance';
            app.AccuracyEditFieldLabel = uilabel(app.ModelPerformancePanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.Accuracy = uieditfield(app.ModelPerformancePanel, 'numeric');
            app.Accuracy.Editable = 'off';
            app.RMSEEditFieldLabel = uilabel(app.ModelPerformancePanel);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSE = uieditfield(app.ModelPerformancePanel, 'numeric');
            app.RMSE.Editable = 'off';
            app.SystemicTherapyPredictionLabel = uilabel(app.UIFigure);
            app.SystemicTherapyPredictionLabel.Text = 'Systemic Therapy Prediction';
        end
    end

    methods (Access = public)

        % Construct app
        function app = allModels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end