classdef oSCORADModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImprovementGraph            matlab.ui.control.UIAxes
        Input1EditFieldLabel        matlab.ui.control.Label
        Input1EditField             matlab.ui.control.NumericEditField
        Input5EditFieldLabel        matlab.ui.control.Label
        Input5EditField             matlab.ui.control.NumericEditField
        CalculateButton             matlab.ui.control.Button
        MCIDEditFieldLabel          matlab.ui.control.Label
        MCIDout                     matlab.ui.control.NumericEditField
        EditFieldLabel              matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        EditField2Label             matlab.ui.control.Label
        EditField2                  matlab.ui.control.NumericEditField
        EditField3Label             matlab.ui.control.Label
        EditField3                  matlab.ui.control.NumericEditField
        Name1EditFieldLabel         matlab.ui.control.Label
        Name1EditField              matlab.ui.control.EditField
        Name2EditFieldLabel         matlab.ui.control.Label
        Name2EditField              matlab.ui.control.EditField
        SeverityScoreDropDownLabel  matlab.ui.control.Label
        SeverityScoreDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.MCIDout.Value = app.Input1EditField.Value + app.Input5EditField.Value;
        end

        % Value changed function: SeverityScoreDropDown
        function SeverityScoreDropDownValueChanged(app, event)
            value = app.SeverityScoreDropDown.Value;
            
            if isequal(value, 'oSCORAD')
                app.Name1EditField.Value = 'o';
                app.Name2EditField.Value = 'o';
            
            elseif isequal(value, 'SCORAD')
                app.Name1EditField.Value = 'tot';
                app.Name2EditField.Value = 'tot';
            else 
                app.Name1EditField.Value = 'error';
                app.Name2EditField.Value = 'error';
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImprovementGraph = uiaxes(app.UIFigure);
            title(app.ImprovementGraph, 'Improvement over time')
            xlabel(app.ImprovementGraph, 'Number of weeks')
            ylabel(app.ImprovementGraph, 'oSCORAD')
            app.Input1EditFieldLabel = uilabel(app.UIFigure);
            app.Input1EditFieldLabel.Text = 'Input 1';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Input5EditFieldLabel = uilabel(app.UIFigure);
            app.Input5EditFieldLabel.Text = 'Input 5';
            app.Input5EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MCIDEditFieldLabel = uilabel(app.UIFigure);
            app.MCIDEditFieldLabel.Text = 'MCID';
            app.MCIDout = uieditfield(app.UIFigure, 'numeric');
            app.MCIDout.ValueDisplayFormat = '%.3f';
            app.MCIDout.Editable = 'off';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.Name1EditFieldLabel = uilabel(app.UIFigure);
            app.Name1EditFieldLabel.Text = 'Name1';
            app.Name1EditField = uieditfield(app.UIFigure, 'text');
            app.Name1EditField.Editable = 'off';
            app.Name2EditFieldLabel = uilabel(app.UIFigure);
            app.Name2EditFieldLabel.Text = 'Name 2';
            app.Name2EditField = uieditfield(app.UIFigure, 'text');
            app.Name2EditField.Editable = 'off';
            app.SeverityScoreDropDownLabel = uilabel(app.UIFigure);
            app.SeverityScoreDropDownLabel.Text = 'Severity Score';
            app.SeverityScoreDropDown = uidropdown(app.UIFigure);
            app.SeverityScoreDropDown.Items = {'oSCORAD', 'SCORAD', 'EASI'};
            app.SeverityScoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SeverityScoreDropDownValueChanged, true);
            app.SeverityScoreDropDown.Value = 'oSCORAD';
        end
    end

    methods (Access = public)

        % Construct app
        function app = oSCORADModel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end